classdef ComplexGraphing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CommonFunctionsDropDown       matlab.ui.control.DropDown
        CommonFunctionsDropDownLabel  matlab.ui.control.Label
        FuncitonEditField             matlab.ui.control.EditField
        FuncitonEditFieldLabel        matlab.ui.control.Label
        YScaleEditField               matlab.ui.control.NumericEditField
        YScaleEditFieldLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        XScaleEditField               matlab.ui.control.NumericEditField
        XScaleEditFieldLabel          matlab.ui.control.Label
        ResolutionEditField           matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Brycen Daniels, Gabriel Cammack-Coleman, and Cameron Miller
    properties (Access = private)
        L = 0;
        Rx = 0;
        Ry = 0;
        func;
    end
    
    methods (Access = private)
        
        function DomianColor(app, L , Rx, Ry)

            xlim(app.UIAxes,'tight');
            ylim(app.UIAxes, 'tight');

            % sets up variables and functions for the rest of the code
            f = app.func;
            x=linspace(-Rx,Rx,L);
            y=linspace(Ry,-Ry,L);
            [X,Y] = meshgrid(x,y);
            Z=f(X + 1i.*Y);
            x=[-Rx Rx];
            y=[-Ry Ry];
        
            % argument and magnitude for every point in Z
            argZ=(angle(Z)+pi)/(2.*pi);
            magZ=mod(0.5.*log(1+real(Z).^2+imag(Z).^2),1)./1;
        
            % turns the arg and mag values into a set of colormaps
            argZmap=ind2rgb(uint8(255.*argZ),hsv);
            magZmap=ind2rgb(uint8(255.*magZ),gray);
        
            % plots the final result
            imagesc(x,-y,magZmap,"AlphaData",0.8,'Parent',app.UIAxes)
            hold(app.UIAxes, 'on')
            imagesc(x,-y,argZmap,"AlphaData",0.55,'Parent',app.UIAxes)
            app.UIAxes.YDir = 'normal';
            hold(app.UIAxes, 'off')
            end
            
            function startUp(app)
                axis(app.UIAxes, 'image');
        end
        
        function g=gamma(app, z)
            c=[24309.2517827 -67451.3794262 71084.7561084 -35575.0088977 ...
            8642.23730492 -931.808427232 35.2240350789 -0.265389291384 ...
            0.0000674177040788 0];
            sum=0;
            for i=1:9
                sum = sum + c(i)./(z+i);
            end
            g=(z+10).^(z+0.5).*exp(-z-10).*(sqrt(2.*pi)+sum);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.L = value;
            
        end

        % Value changed function: XScaleEditField
        function XScaleEditFieldValueChanged(app, event)
            value = app.XScaleEditField.Value;
            app.Rx= value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if(app.FuncitonEditField.Value ~= "" || app.CommonFunctionsDropDown.Value ~= "Select") 
                DomianColor(app, app.L,app.Rx, app.Ry);
            end
        end

        % Value changed function: YScaleEditField
        function YScaleEditFieldValueChanged(app, event)
            value = app.YScaleEditField.Value;
            app.Ry= value;
        end

        % Value changed function: FuncitonEditField
        function FuncitonEditFieldValueChanged(app, event)
            value = app.FuncitonEditField.Value;
            if(app.FuncitonEditField.Value ~= "") 
                app.func = str2func(['@(z) ' value]);
            end
        end

        % Value changed function: CommonFunctionsDropDown
        function CommonFunctionsDropDownValueChanged(app, event)
            switch app.CommonFunctionsDropDown.Value
                case 'Select'
                    value = app.FuncitonEditField.Value;
                    if(app.FuncitonEditField.Value ~= "") 
                       app.func = str2func(['@(z) ' value]);
                    end
                case 'Gamma'
                    app.func = @(z) gamma(app, z);
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Real')
            ylabel(app.UIAxes, 'Imaginary')
            zlabel(app.UIAxes, 'Z')
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.XScaleEditFieldLabel = uilabel(app.UIFigure);
            app.XScaleEditFieldLabel.Text = 'X-Scale';
            app.XScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.XScaleEditField.ValueChangedFcn = createCallbackFcn(app, @XScaleEditFieldValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.YScaleEditFieldLabel = uilabel(app.UIFigure);
            app.YScaleEditFieldLabel.Text = 'Y-Scale';
            app.YScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.YScaleEditField.ValueChangedFcn = createCallbackFcn(app, @YScaleEditFieldValueChanged, true);
            app.FuncitonEditFieldLabel = uilabel(app.UIFigure);
            app.FuncitonEditFieldLabel.Text = 'Funciton:';
            app.FuncitonEditField = uieditfield(app.UIFigure, 'text');
            app.FuncitonEditField.ValueChangedFcn = createCallbackFcn(app, @FuncitonEditFieldValueChanged, true);
            app.CommonFunctionsDropDownLabel = uilabel(app.UIFigure);
            app.CommonFunctionsDropDownLabel.Text = 'Common Functions';
            app.CommonFunctionsDropDown = uidropdown(app.UIFigure);
            app.CommonFunctionsDropDown.Items = {'Select', 'Gamma'};
            app.CommonFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @CommonFunctionsDropDownValueChanged, true);
            app.CommonFunctionsDropDown.Value = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComplexGraphing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end